classdef el_ctrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        CenterPanel                    matlab.ui.container.Panel
        RunButton                      matlab.ui.control.Button
        FieldDropDownLabel             matlab.ui.control.Label
        FieldDropDown                  matlab.ui.control.DropDown
        StatusLampLabel                matlab.ui.control.Label
        StatusLamp                     matlab.ui.control.Lamp
        StopButton                     matlab.ui.control.Button
        ControllerDropDownLabel        matlab.ui.control.Label
        ControllerDropDown             matlab.ui.control.DropDown
        NoiseDropDownLabel             matlab.ui.control.Label
        NoiseDropDown                  matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        FeedForwardGainEditFieldLabel  matlab.ui.control.Label
        FeedForwardGainEditField       matlab.ui.control.NumericEditField
        FeedbackGainEditFieldLabel     matlab.ui.control.Label
        FeedbackGainEditField          matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            
            FieldDropDownValueChanged(app, '');
            ControllerDropDownValueChanged(app, '');
            NoiseDropDownValueChanged(app, '');
            FeedForwardGainEditFieldValueChanged(app, '');
            FeedbackGainEditFieldValueChanged(app, '');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StopButton.Enable = true;
            app.RunButton.Enable  = false;
            app.StatusLamp.Color = [0 1 0];            
            
            global field_id controller_func;
            global ff_gain fb_gain noise_flag;
            simulation(field_id, controller_func, [ff_gain fb_gain], noise_flag);
            
            app.StopButton.Enable = false;
            app.RunButton.Enable  = true;
            app.StatusLamp.Color = [1 0 0];
        end

        % Value changed function: FieldDropDown
        function FieldDropDownValueChanged(app, event)
            global field_id;
            switch app.FieldDropDown.Value
                case '直線'
                    field_id = '01';
                case '太直線'
                    field_id = '18';
                case '円'
                    field_id = '17';
                case 'トラック'
                    field_id = '15';
                case '８の字'
                    field_id = '16';
            end
            
            run("fields/set_field_list.m");
            field_folder = field_list(field_id);
            
            func.makeField(field_folder);
            
            load(strcat("fields/" , field_folder, "/", "field"));
            
            func.drawInit(app.UIAxes, field_size);
            func.drawField(app.UIAxes, field_size, field_line, field_wall, finish_zone);
            
            field_folder = strrep(field_folder,'_',' ');
            app.UIAxes.Title.String = strcat('Field Preview: ',app.FieldDropDown.Value);
            app.UIAxes.Title.FontSize = 16;
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
            global controller_func;
            switch app.ControllerDropDown.Value
                case 'フィードフォワード制御'
                    controller_func = @controllers.FF_control;
                    app.FeedbackGainEditField.Enable = false;
                case 'バンバン制御'
                    controller_func = @controllers.BANGBANG_control;
                    app.FeedbackGainEditField.Enable = true;
                case 'P制御'
                    controller_func = @controllers.P_control;
                    app.FeedbackGainEditField.Enable = true;
            end
        end

        % Value changed function: FeedForwardGainEditField
        function FeedForwardGainEditFieldValueChanged(app, event)
            global ff_gain;
            ff_gain = app.FeedForwardGainEditField.Value;
        end

        % Value changed function: FeedbackGainEditField
        function FeedbackGainEditFieldValueChanged(app, event)
           global fb_gain;
           fb_gain = app.FeedbackGainEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global stop_call;
            stop_call = true;
        end

        % Value changed function: NoiseDropDown
        function NoiseDropDownValueChanged(app, event)
            global noise_flag;
            switch app.NoiseDropDown.Value
                case 'なし'
                    noise_flag = false;
                case 'あり'
                    noise_flag = true;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {479, '1x', 162};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Field View')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CenterPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.CenterPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.FieldDropDownLabel = uilabel(app.CenterPanel);
            app.FieldDropDownLabel.Text = 'Field';
            app.FieldDropDown = uidropdown(app.CenterPanel);
            app.FieldDropDown.Items = {'直線', '太直線', '円', 'トラック', '８の字'};
            app.FieldDropDown.ValueChangedFcn = createCallbackFcn(app, @FieldDropDownValueChanged, true);
            app.FieldDropDown.Value = '直線';
            app.StatusLampLabel = uilabel(app.CenterPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.CenterPanel);
            app.StopButton = uibutton(app.CenterPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ControllerDropDownLabel = uilabel(app.CenterPanel);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.CenterPanel);
            app.ControllerDropDown.Items = {'フィードフォワード制御', 'バンバン制御', 'P制御'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'フィードフォワード制御';
            app.NoiseDropDownLabel = uilabel(app.CenterPanel);
            app.NoiseDropDownLabel.Text = {'Noise'; ''};
            app.NoiseDropDown = uidropdown(app.CenterPanel);
            app.NoiseDropDown.Items = {'なし', 'あり'};
            app.NoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseDropDownValueChanged, true);
            app.NoiseDropDown.Value = 'なし';
            app.RightPanel = uipanel(app.GridLayout);
            app.FeedForwardGainEditFieldLabel = uilabel(app.RightPanel);
            app.FeedForwardGainEditFieldLabel.Text = 'Feed Forward Gain';
            app.FeedForwardGainEditField = uieditfield(app.RightPanel, 'numeric');
            app.FeedForwardGainEditField.ValueChangedFcn = createCallbackFcn(app, @FeedForwardGainEditFieldValueChanged, true);
            app.FeedForwardGainEditField.Value = 0.5;
            app.FeedbackGainEditFieldLabel = uilabel(app.RightPanel);
            app.FeedbackGainEditFieldLabel.Text = 'Feedback Gain';
            app.FeedbackGainEditField = uieditfield(app.RightPanel, 'numeric');
            app.FeedbackGainEditField.ValueChangedFcn = createCallbackFcn(app, @FeedbackGainEditFieldValueChanged, true);
            app.FeedbackGainEditField.Value = 0.1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = el_ctrl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end